classdef testDEGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TestFunctiionListBoxLabel  matlab.ui.control.Label
        TestFunctiionListBox       matlab.ui.control.ListBox
        AlgorithmListBoxLabel      matlab.ui.control.Label
        AlgorithmListBox           matlab.ui.control.ListBox
        NtEditFieldLabel           matlab.ui.control.Label
        NtEditField                matlab.ui.control.NumericEditField
        NiEditFieldLabel           matlab.ui.control.Label
        NiEditField                matlab.ui.control.NumericEditField
        NpEditFieldLabel           matlab.ui.control.Label
        NpEditField                matlab.ui.control.NumericEditField
        NdEditFieldLabel           matlab.ui.control.Label
        NdEditField                matlab.ui.control.NumericEditField
        RunButton                  matlab.ui.control.Button
        PlotButton                 matlab.ui.control.Button
        FEditFieldLabel            matlab.ui.control.Label
        FEditField                 matlab.ui.control.NumericEditField
        CREditFieldLabel           matlab.ui.control.Label
        CREditField                matlab.ui.control.NumericEditField
        RunModelCheckBox           matlab.ui.control.CheckBox
        RecordCheckBox             matlab.ui.control.CheckBox
        PlotNiEditFieldLabel       matlab.ui.control.Label
        PlotNiEditField            matlab.ui.control.NumericEditField
        PlotPathEditFieldLabel     matlab.ui.control.Label
        PlotPathEditField          matlab.ui.control.EditField
    end

    
    properties (Access = public)
        mTestFunction % Description
        mAlgorithm % Description
        mNt % Description
        mNi % Description
        mNp % Description
        mNd % Description
        mF
        mCR
        plot_ni % Description
        plot_path
    end

    properties (Access = private)

    end
    
    methods (Access = public)
        
        
        %         function results = saveCell(cell,nameAlgorithm)
        %             [M,N]=size(cell);
        %             for m=1:M
        %                 dlmwrite([nameAlgorithm,'/',cell{m},'.csv'],mat);
        %             end
        %         end


    end
    
    methods (Access = private)
        
        function results = saveMat(mat,nameFunction,nameAlgorithm)
            dlmwrite([nameAlgorithm,'/',nameFunction,'.csv'],mat);
        end
        
        function results = init(app)
            app.mNt = app.NtEditField.Value;
            app.mNi = app.NiEditField.Value;
            app.mNp = app.NpEditField.Value;
            app.mNd = app.NdEditField.Value;
            app.mF = app.FEditField.Value;
            app.mCR = app.CREditField.Value;
            app.mAlgorithm=app.AlgorithmListBox.Value;
            app.mTestFunction=app.TestFunctiionListBox.Value;
            app.plot_ni=app.PlotNiEditField.Value;
            app.plot_path=app.PlotPathEditField.Value;
        end
        
        function results = drawFig(avgFitness,frc,index,nameFunction,line,algorithm)        
            n=size(avgFitness,2);
            x=1:n;
            y=avgFitness;
            subplot(frc(1),frc(2),index);
            semilogy(x,y,line,'DisplayName',[algorithm,' ',num2str(min(y))]);
            grid on
            hold on
            title(nameFunction);
        end
        
    end
    

    methods (Access = private)

        % Callback function
        function testFunction(app, event)
            value = app.TestFunctiionListBox.Value;
            %             app.mTestFunction=value;
        end

        % Callback function
        function Algorithm(app, event)
            value = app.AlgorithmListBox.Value;
            %             app.mAlgorithm=value;
        end

        % Callback function
        function setNt(app, event)
            value = app.NtEditField.Value;
            %             app.mNt=value;
        end

        % Callback function
        function setNi(app, event)
            value = app.NiEditField.Value;
            %             app.mNi=value;
        end

        % Callback function
        function setNp(app, event)
            value = app.NpEditField.Value;
            %             app.mNp=value;
        end

        % Callback function
        function setNd(app, event)
            value = app.NdEditField.Value;
            %             app.mNd=value;
        end

        % Button pushed function: RunButton
        function Run(app, event)
            
            Algorithm='';
            fitFunction='';
            app.init();
            meanValue=zeros(1,8);
            lastColValue=zeros(app.mNt,8);
            [~,col_Algorithm]=size(app.mAlgorithm);
            [~,col_TestFunction]=size(app.mTestFunction);
            for a=1:col_Algorithm
                nameAlgorithm=app.mAlgorithm{a};
                app.init();
                switch nameAlgorithm
                    case 'STDE'
                        Algorithm=@STDE;
                        a=1;
                    case 'RMDE'
                        Algorithm=@RMDE;
                        a=2;
                    case 'SRDE'
                        Algorithm=@SRDE;
                        a=3;
                    case 'SaDE'
                        Algorithm=@SaDE;
                        a=4;
                    case 'JADE'
                        Algorithm=@JADE;
                        a=5;
                    case 'SSDE'
                        Algorithm=@SSDE;
                        a=6;
                    case 'MDE'
                        Algorithm=@MDE;
                        a=7;
                    case 'MDE_pBX'
                        Algorithm=@MDE_pBX;
                        a=8;
                end              
                for f=1:col_TestFunction
                    nameFunction=app.mTestFunction{f};
                    app.mNd = app.NdEditField.Value;
                    switch nameFunction
                        case 'f1'
                            fitFunction=@f1;
                            upLow=100;
                            index=1;
                            
                        case 'f2'
                            fitFunction=@f2;
                            upLow=10;
                            index=2;
                            
                        case 'f3'
                            fitFunction=@f3;
            %                             upLow=32;
                            upLow=65.536;
                            index=3;
                            
                        case 'f4'
                            fitFunction=@f4;
            %                             upLow=100;
                            upLow=100;
                            index=4;
                            
                        case 'f5'
                            fitFunction=@f5;
                            upLow=30;
                            index=5;
                            
                        case 'f6'
                            fitFunction=@f6;
                            upLow=100;
                            index=6;
                            
                        case 'f7'
                            fitFunction=@f7;
                            upLow=1.28;
                            index=7;
                            
                        case 'f8'
                            fitFunction=@f8;
                                        upLow=500;
            
                            index=8;
                            
                        case 'f9'
                            fitFunction=@f9;
                            upLow=5.12;
                            index=9;
                            
                        case 'f10'
                            fitFunction=@f10;
                            upLow=32;
                            index=10;
                            
                        case 'f11'
                            fitFunction=@f11;
                            upLow=600;
                            index=11;
                            
                        case 'f12'
                            fitFunction=@f12;
                            upLow=1;
                            index=12;
                            
                        case 'f13'
                            fitFunction=@f13;
                            upLow=100;
                            index=13;
                            
                        case 'f14'
                            fitFunction=@f16;
                            app.mNd=2;
                            upLow=512;
                            index=14;
                            
            %                         case 'f15'
            %                             fitFunction=@f15;
            %                             app.mNd=2;
            %                             upLow=2;
            %                             index=15;
                            
                        case 'f15'
                            fitFunction=@f101;
                            upLow=100;
                            index=15;
                            
                        case 'f16'
                            fitFunction=@f110;
                            upLow=32;
                            index=16;
                    end                    
                    tic;
                    [fitBests,nItersions] = Algorithm(app.mNt,app.mNi,app.mNp,app.mNd,app.mF,app.mCR,upLow,fitFunction);
                    
                    f_toc=toc;
                    f_endCol=fitBests(:,end);
                    lastColValue(:,a)=f_endCol;
                    f_min_best=min(f_endCol,[],1)';
                    f_max_best=max(f_endCol,[],1)';
                    f_median_best=median(f_endCol,1)';
                    f_mean_best=mean(f_endCol,1)';
                    f_std_best=std(f_endCol,1)';
                    meanValue(1,a)=f_mean_best;
                    if app.RecordCheckBox.Value==1
                        str=[app.plot_path,'/',nameAlgorithm,'/',nameFunction];
            %                         if ~exist(str,'dir')
                            mkdir(str);         % 若不存在，在当前目录中产生一个子目录‘Figure’
            %                         end
                        dlmwrite([str,'/',nameFunction,'.csv'],fitBests);
                        dlmwrite([str,'/',nameFunction,'mean.csv'],mean(fitBests));
                        dlmwrite([str,'/',nameFunction,'analysis.csv'],[f_min_best, f_max_best, f_median_best, f_mean_best, f_std_best, f_toc]);
                    end
                end               
            end
            if app.RecordCheckBox.Value==1
                        str=['testA'];
                        if ~exist(str,'dir')
                            mkdir(str); 
                        end
                   dlmwrite([str,'/lastcol.csv'],lastColValue);  
            end
            
            disp('STDE      RMDE      SRDE      SaDE     JADE      SSDE       MDE       MDE_pBX');
            format shortE;
            disp(num2str(meanValue));
            app.RunButton.Enable='off';
            app.RunModelCheckBox.Value=0;
            app.RecordCheckBox.Value=0;
            
        end

        % Button pushed function: PlotButton
        function Plot(app, event)
            fig1=figure(1);
            clf(fig1);
            frc=[4,4];
            app.init();
            [~,col_Algorithm]=size(app.mAlgorithm);
            [~,col_TestFunction]=size(app.mTestFunction);
            for f=1:col_TestFunction
                nameFunction=app.mTestFunction{f};
                switch nameFunction
                        case 'f1'
                            index=1;                          
                        case 'f2'
                            index=2;                          
                        case 'f3'
                            index=3;
                        case 'f4'
                            index=4;                           
                        case 'f5'
                            index=5;                           
                        case 'f6'
                            index=6;                          
                        case 'f7'
                            index=7;                          
                        case 'f8'
                            index=8;                           
                        case 'f9'
                            index=9;                           
                        case 'f10'
                            index=10;                           
                        case 'f11'
                            index=11;                            
                        case 'f12'
                            index=12;                          
                        case 'f13'
                            index=13;                          
                        case 'f14'
                            index=14;                           
                        case 'f15'
                            index=15;                           
                        case 'f16'
                            index=16;
                end
                for a=1:col_Algorithm
                    nameAlgorithm=app.mAlgorithm{a};
                    switch nameAlgorithm
                        case 'STDE'
                            line='r-';
                        case 'RMDE'
                            line='k-';
                        case 'SRDE'
                            line='g-';
                        case 'SaDE'
                            line='m-';
                        case 'JADE'
                            line='y-';
                        case 'SSDE'
                            line='b-';
                        case 'MDE'
                            line='c-';
                        case 'MDE_pBX'
                            line='r--';
                    end
                    file_str=[app.plot_path,'/',nameAlgorithm,'/',nameFunction,'/',nameFunction,'mean.csv'];
                    if ~exist(file_str,'file')
                        disp(['there is no ',file_str]);
                        break;
                    end
                    mean_fitBests=dlmread(file_str);
                    % app.drawFig(mean_fitBests,frc,f,nameFunction,lines{a},nameAlgorithm);
                    n=size(mean_fitBests,2);
                                if app.plot_ni==0
                                     app.plot_ni=n;
                                elseif app.plot_ni>n
                                    app.plot_ni=n;
                                end


                                x=1:app.plot_ni;
                                y=mean_fitBests(1:app.plot_ni);
            %             x=1:n;
            %             y=mean_fitBests;
            
                    subplot(frc(1),frc(2),index);
                    semilogy(x,y,line,'DisplayName',[nameAlgorithm,' ',num2str(min(y))]);
                    grid on
                    hold on
                    title(nameFunction);
                end
            end
            
            for s=1:16
                subplot(frc(1),frc(2),s);
                legend('show');
            end
        end

        % Callback function
        function setF(app, event)
            value = app.FEditField.Value;
            %             app.mF=value;
        end

        % Callback function
        function setCR(app, event)
            value = app.CREditField.Value;
            %             app.mCR=value;
        end

        % Value changed function: RunModelCheckBox
        function isRun(app, event)
            value = app.RunModelCheckBox.Value;
            if value==0
               app.RunButton.Enable = 'off';
            elseif value==1
               app.RunButton.Enable = 'on';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.TestFunctiionListBoxLabel = uilabel(app.UIFigure);
            app.TestFunctiionListBoxLabel.Text = 'TestFunctiion';
            app.TestFunctiionListBox = uilistbox(app.UIFigure);
            app.TestFunctiionListBox.Items = {'f1', 'f2', 'f3', 'f4', 'f5', 'f6', 'f7', 'f8', 'f9', 'f10', 'f11', 'f12', 'f13', 'f14', 'f15', 'f16'};
            app.TestFunctiionListBox.Multiselect = 'on';
            app.TestFunctiionListBox.Value = {'f1'};
            app.AlgorithmListBoxLabel = uilabel(app.UIFigure);
            app.AlgorithmListBoxLabel.Text = 'Algorithm';
            app.AlgorithmListBox = uilistbox(app.UIFigure);
            app.AlgorithmListBox.Items = {'STDE', 'RMDE', 'SRDE', 'JADE', 'SSDE', 'MDE', 'SaDE', 'MDE_pBX'};
            app.AlgorithmListBox.Multiselect = 'on';
            app.AlgorithmListBox.Value = {'STDE'};
            app.NtEditFieldLabel = uilabel(app.UIFigure);
            app.NtEditFieldLabel.Text = 'Nt';
            app.NtEditField = uieditfield(app.UIFigure, 'numeric');
            app.NtEditField.Limits = [0 50];
            app.NtEditField.Value = 3;
            app.NiEditFieldLabel = uilabel(app.UIFigure);
            app.NiEditFieldLabel.Text = 'Ni';
            app.NiEditField = uieditfield(app.UIFigure, 'numeric');
            app.NiEditField.Limits = [0 1000000];
            app.NiEditField.Value = 2000;
            app.NpEditFieldLabel = uilabel(app.UIFigure);
            app.NpEditFieldLabel.Text = 'Np';
            app.NpEditField = uieditfield(app.UIFigure, 'numeric');
            app.NpEditField.Limits = [0 20000];
            app.NpEditField.Value = 100;
            app.NdEditFieldLabel = uilabel(app.UIFigure);
            app.NdEditFieldLabel.Text = 'Nd';
            app.NdEditField = uieditfield(app.UIFigure, 'numeric');
            app.NdEditField.Limits = [0 100];
            app.NdEditField.Value = 30;
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @Run, true);
            app.RunButton.Enable = 'off';
            app.RunButton.Text = 'Run';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @Plot, true);
            app.PlotButton.Text = 'Plot';
            app.FEditFieldLabel = uilabel(app.UIFigure);
            app.FEditFieldLabel.Text = 'F';
            app.FEditField = uieditfield(app.UIFigure, 'numeric');
            app.FEditField.Value = 0.5;
            app.CREditFieldLabel = uilabel(app.UIFigure);
            app.CREditFieldLabel.Text = 'CR';
            app.CREditField = uieditfield(app.UIFigure, 'numeric');
            app.CREditField.Value = 0.3;
            app.RunModelCheckBox = uicheckbox(app.UIFigure);
            app.RunModelCheckBox.ValueChangedFcn = createCallbackFcn(app, @isRun, true);
            app.RunModelCheckBox.Text = 'RunModel';
            app.RecordCheckBox = uicheckbox(app.UIFigure);
            app.RecordCheckBox.Text = 'Record';
            app.PlotNiEditFieldLabel = uilabel(app.UIFigure);
            app.PlotNiEditFieldLabel.Text = 'Plot  Ni';
            app.PlotNiEditField = uieditfield(app.UIFigure, 'numeric');
            app.PlotPathEditFieldLabel = uilabel(app.UIFigure);
            app.PlotPathEditFieldLabel.Text = 'Plot Path';
            app.PlotPathEditField = uieditfield(app.UIFigure, 'text');
            app.PlotPathEditField.Value = 'testA';
        end
    end

    methods (Access = public)

        % Construct app
        function app = testDEGui()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end